classdef mission < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MissionconfigurationTab         matlab.ui.container.Tab
        HourEditField                   matlab.ui.control.EditField
        HourEditFieldLabel              matlab.ui.control.Label
        DateDatePicker                  matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        RequiredEbNoRx                  matlab.ui.control.EditField
        RequiredEbNoMbpsEditFieldLabel  matlab.ui.control.Label
        GaintoNoiseTemperatureratioRx   matlab.ui.control.EditField
        GaintoNoiseTemperatureratiodBKEditFieldLabel  matlab.ui.control.Label
        PreReceiverLossRx               matlab.ui.control.EditField
        PreReceiverLossdBEditFieldLabel  matlab.ui.control.Label
        SystemLossRx                    matlab.ui.control.EditField
        SystemLossdBEditFieldLabel      matlab.ui.control.Label
        SatelliteReceiverParametersLabel  matlab.ui.control.Label
        showSatPattern                  matlab.ui.control.CheckBox
        satAntenna                      matlab.ui.control.DropDown
        AntennaDropDownLabel            matlab.ui.control.Label
        SatelliteAntennaLabel           matlab.ui.control.Label
        satMountingAnglesTxRx           matlab.ui.control.EditField
        MountingAnglesmLabel            matlab.ui.control.Label
        satPowerTx                      matlab.ui.control.EditField
        PowerdBWLabel                   matlab.ui.control.Label
        satBitRateTx                    matlab.ui.control.EditField
        BitRateMbpsLabel                matlab.ui.control.Label
        FrequencyHzLabel                matlab.ui.control.Label
        satFrequencyTx                  matlab.ui.control.EditField
        satMountingLocationTxRx         matlab.ui.control.EditField
        MountingLocationmLabel          matlab.ui.control.Label
        satNameTxRx                     matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        SatelliteTransmitterParametersLabel  matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        CalculateDownLinkButton         matlab.ui.control.RadioButton
        CalculateUpLinkButton           matlab.ui.control.RadioButton
        RunSimulationButton             matlab.ui.control.Button
        gsInfo                          matlab.ui.control.EditField
        SelectxlsxfileButton            matlab.ui.control.Button
        tleInfo                         matlab.ui.control.EditField
        SelecttlefileButton             matlab.ui.control.Button
        GroundstationdataselectionLabel  matlab.ui.control.Label
        OrbitaldataselectionLabel       matlab.ui.control.Label
        MissionParametersLabel          matlab.ui.control.Label
        SimulationconfigurationTab      matlab.ui.container.Tab
        FocusScenarycamerainorbitalplatformCheckBox  matlab.ui.control.CheckBox
        OtherparametersLabel            matlab.ui.control.Label
        ScenaryDurationhEditField       matlab.ui.control.EditField
        ScenaryDurationhEditFieldLabel  matlab.ui.control.Label
        SampletimesEditField            matlab.ui.control.EditField
        SampletimesEditFieldLabel       matlab.ui.control.Label
        SimulationparametersLabel       matlab.ui.control.Label
        ShowgroundtrackCheckBox         matlab.ui.control.CheckBox
        SimulationrepresentationButtonGroup  matlab.ui.container.ButtonGroup
        Button_2D                       matlab.ui.control.ToggleButton
        Button_3D                       matlab.ui.control.ToggleButton
        ResultsTab                      matlab.ui.container.Tab
        GeneratemissionplanButton       matlab.ui.control.Button
        ExporttocsvfileButton           matlab.ui.control.Button
        ExporttoxlsxfileButton          matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SimulationTab                   matlab.ui.container.Tab
        InformationTab                  matlab.ui.container.Tab
        SoftwareVersionv10Label         matlab.ui.control.Label
        DegreeAerospaceEngineeringinNavigationLabel  matlab.ui.control.Label
        AuthorCsarBoraoMoratinosLabel   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MISSIONPLANNERPROGRAMLabel      matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    properties (Access = private)
        tleFile = "None" % Description
        gsFile = "None" % Description
        raw             
    end

    methods (Access = private)

        function sc = createScenario(app)
            % Set mission and simulation configuration
            date = app.DateDatePicker.Value;
            hour = datetime(app.HourEditField.Value, 'InputFormat', 'HH:mm:ss');
              
            startTime =  date + hours(hour.Hour) + minutes(hour.Minute) + seconds(hour.Second);
            duration = datetime(app.ScenaryDurationhEditField.Value, 'InputFormat', 'HH'); %str2double(app.ScenaryDurationhEditField);
            sampleTime = str2double(app.SampletimesEditField.Value);
            stopTime = startTime + hours(duration.Hour);
        
            % Create new scenario
            sc = satelliteScenario(startTime,stopTime,sampleTime);  
        end
        
        function sat = SatFromTle(app,sc)
            try 
                % Create satellite object
                sat = satellite(sc,app.tleFile);
                assignin('base','sat',sat)
                
                % Create if there is only one satellite
                if size(sat,2) ~= 1
                    disp(strcat('Error: File "',app.tleInfo.Value ,'" includes a satellite constellation. Program works with an unique satellite')); 
                    error("More than one satellite included in .tle file")
                end
            catch e
                fprintf(1,'Error: %s\n',e.message);
                return 
            end
        end
    
        function gs = GsFromXlsx(app,sc)
           
            % Read .xlsx file content
            % TODO add more checks: number of columns, etc.
            try
                [~,~,app.raw] = xlsread(app.gsFile);
                assignin('base','raw',app.raw);
            catch e
                fprintf(1,'Error: %s\n',e.message);
                return
            end

            % Create Ground station objects
            for i = 2:size(app.raw,2)
                % Ground station creation
                gsName = app.raw{1,i};
                gsLat = app.raw{2,i};
                gsLon = app.raw{3,i};
                gsMinElevationAngle = app.raw{4,i};
                gs(i-1) = groundStation(sc,"Name",gsName,"Latitude",gsLat,"Longitude",gsLon,"MinElevationAngle",gsMinElevationAngle);
            end
            
            assignin('base','gs',gs)
        end

        function [satRFEquipment, RFtype] = addSatRFEquipment(app, sat)

            % If is Dowlink calculation, equipment is Transmitter
            if app.CalculateDownLinkButton.Value == true
        
                % Satellite transmitter
                satTx = transmitter(sat, ...
                                "Name", app.satNameTxRx.Value, ...
                                "MountingLocation", str2num(app.satMountingLocationTxRx.Value), ... 
                                "MountingAngles", str2num(app.satMountingAnglesTxRx.Value), ... 
                                "Frequency",str2double(app.satFrequencyTx.Value), ...    
                                "BitRate",str2double(app.satBitRateTx.Value), ...
                                "Power",str2double(app.satPowerTx.Value));  

                if app.satAntenna.Value == "Gaussian Antenna"
                    gaussianAntenna(satTx, ...
                            "DishDiameter",0.3); % meters
                else
                    satTx.Antenna = app.satAntenna.Value;
                end

                satRFEquipment = satTx;
                RFtype = "Tx";

            % If is Uplink calculation, equipment is Receiver
            elseif app.CalculateUpLinkButton.Value == true
    
                % Satellite receiver
                satRx = receiver(sat, ...
                                "Name", app.satNameTxRx.Value, ...
                                "MountingLocation", str2num(app.satMountingLocationTxRx.Value), ... 
                                "MountingAngles", str2num(app.satMountingAnglesTxRx.Value), ...
                                "RequiredEbNo", str2double(app.RequiredEbNoRx.Value), ...
                                "SystemLoss", str2double(app.SystemLossRx.Value), ...
                                "PreReceiverLoss", str2double(app.PreReceiverLossRx.Value), ...
                                "GainToNoiseTemperatureRatio", str2double(app.GaintoNoiseTemperatureratioRx.Value), ...
                                "RequiredEbNo", str2double(app.RequiredEbNoRx.Value));    

                if app.satAntenna.Value == "Gaussian Antenna"
                    gaussianAntenna(satRx, ...
                            "DishDiameter",0.3); % meters
                else
                    satRx.Antenna = app.satAntenna.Value;
                end

                satRFEquipment = satRx;
                RFtype = "Rx";

            end

        end

        function addGsRFEquipment(app, gs, sat, satRFEquipment)

            % If is Dowlink calculation, equipment is Receiver
            if app.CalculateDownLinkButton.Value == true

                % Add receiver for each gs
                for i = 2:size(app.raw,2)
                    
                    % Gimbal configuration
                    gimbalMountingLocation = app.raw{5,i};
                    gimbalMountingAngles = app.raw{6,i};
                    if isnan(gimbalMountingLocation)
                        current = gs(i-1);
                    else
                        gsGimbal = gimbal(gs(i-1),"MountingLocation",str2num(gimbalMountingLocation),"MountingAngles",str2num(gimbalMountingAngles));
                  
                        % Set the gimbals to track the satellite.
                        current = gsGimbal;
                        pointAt(current,sat);
                    end  
                    
                    % Set receiver parameters

%                     gsMountingLocationRx = raw{7,i};
%                     if isnan(gsMountingLocationRx)
%                         gsMountingLocationRx = [0;0;0];
%                     end
                    gsGainToNoiseTemperatureRatioRx = app.raw{8,i};
                    if isnan(gsGainToNoiseTemperatureRatioRx)
                        gsGainToNoiseTemperatureRatioRx = 3;
                    end
                    gsRequiredEbNoRx = app.raw{9,i};
                    if isnan(gsRequiredEbNoRx)
                        gsRequiredEbNoRx = 10;
                    end
                    gsReceiverSystemLossRx = app.raw{10,i};
                    if isnan(gsReceiverSystemLossRx)
                        gsReceiverSystemLossRx = 5;
                    end
                    gsPreReceiverLossRx = app.raw{11,i};
                    if isnan(gsPreReceiverLossRx)
                        gsPreReceiverLossRx = 3;
                    end
                    gsAntennaRx = app.raw{12,i};

                    % Create receiver object
                    gsRx(i-1) = receiver(current, ...
                                    "Name", strcat(gs(i-1).Name, ' Rx'), ...
                                    "GainToNoiseTemperatureRatio",gsGainToNoiseTemperatureRatioRx, ... 
                                    "RequiredEbNo",gsRequiredEbNoRx, ...
                                    "SystemLoss",gsReceiverSystemLossRx,...
                                    "PreReceiverLoss",gsPreReceiverLossRx); 
                    if strcmp(gsAntennaRx,'Gaussian')
                         gaussianAntenna(gsRx(i-1), "DishDiameter",5);
                    end

                    % Create link
                    lnk = link(satRFEquipment,gsRx(i-1));
                    
                    % show link intervals
                    intervals = linkIntervals(lnk);

                    % Store results in Results tab
                    currentData = app.UITable.Data;
                    newData = vertcat(currentData, intervals);
                    app.UITable.Data = newData;
                    app.ExporttoxlsxfileButton.Visible = true;
                    app.ExporttocsvfileButton.Visible = true;
                
                end

            % If is Uplink calculation, equipment is Transmitter
            elseif app.CalculateUpLinkButton.Value == true

                % Add receiver for each gs
                for i = 2:size(app.raw,2)

                    % Gimbal configuration
                    gimbalMountingLocation = app.raw{5,i};
                    gimbalMountingAngles = app.raw{6,i};
                    if isnan(gimbalMountingLocation)
                        current = gs(i-1);
                    else
                        gsGimbal = gimbal(gs(i-1),"MountingLocation",str2num(gimbalMountingLocation),"MountingAngles",str2num(gimbalMountingAngles));
                    
                        % Set the gimbals to track the satellite.
                        current = gsGimbal;
                        pointAt(current,sat);
                    end 

                     % Transmitter configuration
                     gsSystemLossTx = app.raw{13,i};
                     if isnan(gsSystemLossTx)
                         gsSystemLossTx = 5;
                     end
                     gsFrequencyTx = app.raw{14,i};
                     if isnan(gsFrequencyTx)
                         gsFrequencyTx = 14e9;
                     end
                     gsBitRateTx = app.raw{15,i};
                     if isnan(gsBitRateTx)
                         gsBitRateTx = 10;
                     end
                     gsPowerTx = app.raw{16,i};
                     if isnan(gsPowerTx)
                         gsPowerTx = 12;
                     end
                     gsAntennaTx = app.raw{12,i};
    
                     % Creating transmitter
                     gsTx(i-1) = transmitter(current, ...
                                        "Name", strcat(gs(i-1).Name, ' Tx'), ...
                                        "SystemLoss",gsSystemLossTx, ... 
                                        "Frequency",gsFrequencyTx, ...
                                        "BitRate",gsBitRateTx,...
                                        "Power",gsPowerTx);                          
    %                                   "MountingLocation",str2num(gsMountingLocationRx), ...
    
                     if strcmp(gsAntennaTx,'Gaussian')
                          gaussianAntenna(gsTx(i-1), "DishDiameter",5);
                     end
                        
                     % Create link
                     lnk = link(gsTx(i-1),satRFEquipment);
    
                     % show link intervals
                     intervals = linkIntervals(lnk);

                     % Store results in Results tab
                     currentData = app.UITable.Data;
                     newData = vertcat(currentData, intervals);
                     app.UITable.Data = newData;
                     app.ExporttoxlsxfileButton.Visible = true;
                     app.ExporttocsvfileButton.Visible = true;
                end
            end      
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Result table initialization
            app.UITable.ColumnName = ["Source","Target","IntervalNumber","StartTime","EndTime","Duration","StartOrbit","EndOrbit"];
            app.UITable.Visible = true;
            app.UITable.Data = [];
            
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)

            % Remove previous results
            app.UITable.Data = [];

            % Create mission scenario 
            sc = createScenario(app);
           
            % Create orbital platform data from selected .tle file
            sat = SatFromTle(app,sc);
           
            % TODO: reminder-> RF equipment is fixed (not gimbaled), check
            % gimbalAngles() function to extract angles for MP.

            % Add RF satellite equipment
            cam = conicalSensor(sat,"Name","Satellite","MaxViewAngle",70);
            [satRFEquipment, ~] = addSatRFEquipment(app, sat);
            
            %---------------------------------------------------------
            % Ground objects info

            gs = GsFromXlsx(app, sc);
            
            addGsRFEquipment(app, gs, sat, satRFEquipment);
            
            %------------------------------------
%             if app.showSatPattern.Value == true
%                if app.CalculateUpLinkButton.Value == true
% %                    pattern(satRx,1e9);
%                    for i = 1:size(gsTx,2)
%                         pattern(gsTx(i));
%                    end
%                 elseif app.CalculateDownLinkButton.Value == true
%                    pattern(satTx);
% %                    for i = 1:size(gsRx,2)
% %                        pattern(gsRx(i),app.satFrequencyTx.Value);
% %                    end
%                 end
%             end


            % Scenary visualization options
            fieldOfView(cam);

            if app.Button_2D.Value == true
                dimension = '2D';
            else
                dimension = '3D';
            end    
            scv = satelliteScenarioViewer(sc,Dimension=dimension);
             
            if app.ShowgroundtrackCheckBox.Value == true
                groundTrack(sat,LeadTime=3600);
            end
            
            if app.FocusScenarycamerainorbitalplatformCheckBox.Value == true
                camtarget(scv,sat)
            end

        end

        % Button pushed function: SelecttlefileButton
        function SelecttlefileButtonPushed(app, event)
            % Get file
            [fileName,filePath] = uigetfile();

            % Check file has name
            if fileName ~= 0
                fullFileName = fullfile(filePath,fileName);
                [~,fileNameNoExt,fileExt] = fileparts(fullFileName);

                % Check file extension is .tle
                if strcmp(fileExt,'.tle')
                    app.tleFile = [fileNameNoExt fileExt];
                    assignin('base','tleSelectedFile',fullFileName);
                    app.tleInfo.Value = app.tleFile;                    
                else
                    app.tleInfo.Value = 'Error: selected file is not .tle';
                end
            end
        end

        % Button pushed function: SelectxlsxfileButton
        function SelectxlsxfileButtonPushed(app, event)
            % Get file
            [fileName,filePath] = uigetfile();

            % Check file has name
            if fileName ~= 0
                fullFileName = fullfile(filePath,fileName);
                [~,fileNameNoExt,fileExt] = fileparts(fullFileName);

                % Check file extension is .xlsx
                if strcmp(fileExt,'.xlsx')
                    app.gsFile= [fileNameNoExt fileExt];
                    app.gsInfo.Value = app.gsFile;
                    assignin('base','gsSelectedFile',fullFileName);
                else
                    app.gsInfo.Value = 'Error: selected file is not .xlsx';
                end
            end            
        end

        % Button down function: ButtonGroup
        function ButtonGroupButtonDown(app, event)
            if app.CalculateDownLinkButton.Value == true
                app.NameEditFieldLabel.Visible = true;
                app.satNameTxRx.Visible = true;
            end  
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
%             selectedButton = app.ButtonGroup.SelectedObject;
            if app.CalculateDownLinkButton.Value == true
                app.SatelliteTransmitterParametersLabel.Visible = true;
                app.SatelliteReceiverParametersLabel.Visible = false;

                app.satFrequencyTx.Visible = true;
                app.FrequencyHzLabel.Visible = true;
                app.satBitRateTx.Visible = true;
                app.BitRateMbpsLabel.Visible = true;
                app.satPowerTx.Visible = true;
                app.PowerdBWLabel.Visible = true;
               
                app.SystemLossRx.Visible = false;
                app.SystemLossdBEditFieldLabel.Visible = false;
                app.PreReceiverLossRx.Visible = false;
                app.PreReceiverLossdBEditFieldLabel.Visible = false;
                app.GaintoNoiseTemperatureratioRx.Visible = false;
                app.GaintoNoiseTemperatureratiodBKEditFieldLabel.Visible = false;
                app.RequiredEbNoRx.Visible = false;
                app.RequiredEbNoMbpsEditFieldLabel.Visible = false;

            end 
            if app.CalculateUpLinkButton.Value == true
                app.SatelliteTransmitterParametersLabel.Visible = false;
                app.SatelliteReceiverParametersLabel.Visible = true;

                app.satFrequencyTx.Visible = false;
                app.FrequencyHzLabel.Visible = false;
                app.satBitRateTx.Visible = false;
                app.BitRateMbpsLabel.Visible = false;
                app.satPowerTx.Visible = false;
                app.PowerdBWLabel.Visible = false;

                app.SystemLossRx.Visible = true;
                app.SystemLossdBEditFieldLabel.Visible = true;
                app.PreReceiverLossRx.Visible = true;
                app.PreReceiverLossdBEditFieldLabel.Visible = true;
                app.GaintoNoiseTemperatureratioRx.Visible = true;
                app.GaintoNoiseTemperatureratiodBKEditFieldLabel.Visible = true;
                app.RequiredEbNoRx.Visible = true; 
                app.RequiredEbNoMbpsEditFieldLabel.Visible = true;
                
            end   
        end

        % Button pushed function: ExporttoxlsxfileButton
        function ExporttoxlsxfileButtonPushed(app, event)
            [fileName, pathName] = uiputfile('*.xlsx','Save as');
            if fileName == 0
                return;
            end
            fullPath = fullfile(pathName, fileName);
            writetable(app.UITable.Data, fullPath, "AutoFitWidth",false);
        end

        % Button pushed function: ExporttocsvfileButton
        function ExporttocsvfileButtonPushed(app, event)
            [file, path] = uiputfile('*.csv', 'Save as');
            if file == 0
                return
            end
            
            filename = fullfile(path, file);
            writetable(app.UITable.Data, filename);
            
        end

        % Button pushed function: GeneratemissionplanButton
        function GeneratemissionplanButtonPushed(app, event)
            [file, path] = uiputfile('*.txt', 'Save as');
            if file == 0
                return
            end
            
            fileId = fopen(fullfile(path, file), 'w');
            fprintf(fileId,'MISSION PLAN\n\n');
            fprintf(fileId,'Generation date: %s\n',datetime("today"));
            fprintf(fileId,'\n---------------------------------------------------------\n');
            fprintf(fileId,'Mission Parameters: \n');
            fprintf(fileId,'\t Date: %s\n\t Hour: %s \n',app.DateDatePicker.Value,app.HourEditField.Value);
            fprintf(fileId,'\n---------------------------------------------------------\n');
            writetable(app.UITable.Data, fullfile(path, file), 'FileType', 'text', 'Delimiter', '\t', 'WriteMode', 'append');
            
            fclose(fileId);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MISSIONPLANNERPROGRAMLabel = uilabel(app.UIFigure);
            app.MISSIONPLANNERPROGRAMLabel.Text = 'MISSION PLANNER PROGRAM';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MissionconfigurationTab = uitab(app.TabGroup);
            app.MissionconfigurationTab.Title = 'Mission configuration';
            app.MissionParametersLabel = uilabel(app.MissionconfigurationTab);
            app.MissionParametersLabel.Text = 'Mission Parameters';
            app.OrbitaldataselectionLabel = uilabel(app.MissionconfigurationTab);
            app.OrbitaldataselectionLabel.Text = 'Orbital data selection';
            app.GroundstationdataselectionLabel = uilabel(app.MissionconfigurationTab);
            app.GroundstationdataselectionLabel.Text = 'Ground station data selection';
            app.SelecttlefileButton = uibutton(app.MissionconfigurationTab, 'push');
            app.SelecttlefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttlefileButtonPushed, true);
            app.SelecttlefileButton.Text = 'Select .tle file';
            app.tleInfo = uieditfield(app.MissionconfigurationTab, 'text');
            app.tleInfo.Enable = 'off';
            app.SelectxlsxfileButton = uibutton(app.MissionconfigurationTab, 'push');
            app.SelectxlsxfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectxlsxfileButtonPushed, true);
            app.SelectxlsxfileButton.Text = 'Select .xlsx file';
            app.gsInfo = uieditfield(app.MissionconfigurationTab, 'text');
            app.gsInfo.Enable = 'off';
            app.RunSimulationButton = uibutton(app.MissionconfigurationTab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ButtonGroup = uibuttongroup(app.MissionconfigurationTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ButtonGroupButtonDown, true);
            app.CalculateUpLinkButton = uiradiobutton(app.ButtonGroup);
            app.CalculateUpLinkButton.Text = 'Calculate UpLink';
            app.CalculateUpLinkButton.Value = true;
            app.CalculateDownLinkButton = uiradiobutton(app.ButtonGroup);
            app.CalculateDownLinkButton.Text = 'Calculate DownLink';
            app.SatelliteTransmitterParametersLabel = uilabel(app.MissionconfigurationTab);
            app.SatelliteTransmitterParametersLabel.Visible = 'off';
            app.SatelliteTransmitterParametersLabel.Text = 'Satellite Transmitter Parameters';
            app.NameEditFieldLabel = uilabel(app.MissionconfigurationTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.satNameTxRx = uieditfield(app.MissionconfigurationTab, 'text');
            app.satNameTxRx.Value = 'sat';
            app.MountingLocationmLabel = uilabel(app.MissionconfigurationTab);
            app.MountingLocationmLabel.Text = 'Mounting Location (m)';
            app.satMountingLocationTxRx = uieditfield(app.MissionconfigurationTab, 'text');
            app.satMountingLocationTxRx.Value = '[0;0;0]';
            app.satFrequencyTx = uieditfield(app.MissionconfigurationTab, 'text');
            app.satFrequencyTx.Visible = 'off';
            app.satFrequencyTx.Value = '1e9';
            app.FrequencyHzLabel = uilabel(app.MissionconfigurationTab);
            app.FrequencyHzLabel.Visible = 'off';
            app.FrequencyHzLabel.Text = 'Frequency (Hz)';
            app.BitRateMbpsLabel = uilabel(app.MissionconfigurationTab);
            app.BitRateMbpsLabel.Visible = 'off';
            app.BitRateMbpsLabel.Text = 'Bit Rate (Mbps)';
            app.satBitRateTx = uieditfield(app.MissionconfigurationTab, 'text');
            app.satBitRateTx.Visible = 'off';
            app.satBitRateTx.Value = '10';
            app.PowerdBWLabel = uilabel(app.MissionconfigurationTab);
            app.PowerdBWLabel.Visible = 'off';
            app.PowerdBWLabel.Text = 'Power (dBW)';
            app.satPowerTx = uieditfield(app.MissionconfigurationTab, 'text');
            app.satPowerTx.Visible = 'off';
            app.satPowerTx.Value = '12';
            app.MountingAnglesmLabel = uilabel(app.MissionconfigurationTab);
            app.MountingAnglesmLabel.Text = 'Mounting Angles (m)';
            app.satMountingAnglesTxRx = uieditfield(app.MissionconfigurationTab, 'text');
            app.satMountingAnglesTxRx.Value = '[0;0;0]';
            app.SatelliteAntennaLabel = uilabel(app.MissionconfigurationTab);
            app.SatelliteAntennaLabel.Text = 'Satellite Antenna';
            app.AntennaDropDownLabel = uilabel(app.MissionconfigurationTab);
            app.AntennaDropDownLabel.Text = 'Antenna';
            app.satAntenna = uidropdown(app.MissionconfigurationTab);
            app.satAntenna.Items = {'Gaussian Antenna'};
            app.satAntenna.Value = 'Gaussian Antenna';
            app.showSatPattern = uicheckbox(app.MissionconfigurationTab);
            app.showSatPattern.Text = 'Show satellite Antenna Pattern';
            app.SatelliteReceiverParametersLabel = uilabel(app.MissionconfigurationTab);
            app.SatelliteReceiverParametersLabel.Text = 'Satellite Receiver Parameters';
            app.SystemLossdBEditFieldLabel = uilabel(app.MissionconfigurationTab);
            app.SystemLossdBEditFieldLabel.Text = 'System Loss (dB)';
            app.SystemLossRx = uieditfield(app.MissionconfigurationTab, 'text');
            app.SystemLossRx.Value = '5';
            app.PreReceiverLossdBEditFieldLabel = uilabel(app.MissionconfigurationTab);
            app.PreReceiverLossdBEditFieldLabel.Text = 'PreReceiver Loss (dB)';
            app.PreReceiverLossRx = uieditfield(app.MissionconfigurationTab, 'text');
            app.PreReceiverLossRx.Value = '3';
            app.GaintoNoiseTemperatureratiodBKEditFieldLabel = uilabel(app.MissionconfigurationTab);
            app.GaintoNoiseTemperatureratiodBKEditFieldLabel.Text = 'Gain to Noise Temperature ratio (dB/K)';
            app.GaintoNoiseTemperatureratioRx = uieditfield(app.MissionconfigurationTab, 'text');
            app.GaintoNoiseTemperatureratioRx.Value = '3';
            app.RequiredEbNoMbpsEditFieldLabel = uilabel(app.MissionconfigurationTab);
            app.RequiredEbNoMbpsEditFieldLabel.Text = 'Required Eb/No (Mbps)';
            app.RequiredEbNoRx = uieditfield(app.MissionconfigurationTab, 'text');
            app.RequiredEbNoRx.Value = '10';
            app.DateDatePickerLabel = uilabel(app.MissionconfigurationTab);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.MissionconfigurationTab);
            app.DateDatePicker.Value = datetime([2024 3 6]);
            app.HourEditFieldLabel = uilabel(app.MissionconfigurationTab);
            app.HourEditFieldLabel.Text = 'Hour';
            app.HourEditField = uieditfield(app.MissionconfigurationTab, 'text');
            app.HourEditField.Value = '09:00:00';
            app.SimulationconfigurationTab = uitab(app.TabGroup);
            app.SimulationconfigurationTab.Title = 'Simulation configuration';
            app.SimulationrepresentationButtonGroup = uibuttongroup(app.SimulationconfigurationTab);
            app.SimulationrepresentationButtonGroup.Title = 'Simulation representation';
            app.Button_3D = uitogglebutton(app.SimulationrepresentationButtonGroup);
            app.Button_3D.Text = '3D';
            app.Button_3D.Value = true;
            app.Button_2D = uitogglebutton(app.SimulationrepresentationButtonGroup);
            app.Button_2D.Text = '2D';
            app.ShowgroundtrackCheckBox = uicheckbox(app.SimulationconfigurationTab);
            app.ShowgroundtrackCheckBox.Text = 'Show ground track';
            app.ShowgroundtrackCheckBox.Value = true;
            app.SimulationparametersLabel = uilabel(app.SimulationconfigurationTab);
            app.SimulationparametersLabel.Text = 'Simulation parameters';
            app.SampletimesEditFieldLabel = uilabel(app.SimulationconfigurationTab);
            app.SampletimesEditFieldLabel.Text = 'Sample time (s)';
            app.SampletimesEditField = uieditfield(app.SimulationconfigurationTab, 'text');
            app.SampletimesEditField.Value = '30';
            app.ScenaryDurationhEditFieldLabel = uilabel(app.SimulationconfigurationTab);
            app.ScenaryDurationhEditFieldLabel.Text = 'Scenary Duration (h)';
            app.ScenaryDurationhEditField = uieditfield(app.SimulationconfigurationTab, 'text');
            app.ScenaryDurationhEditField.Value = '20';
            app.OtherparametersLabel = uilabel(app.SimulationconfigurationTab);
            app.OtherparametersLabel.Text = 'Other parameters';
            app.FocusScenarycamerainorbitalplatformCheckBox = uicheckbox(app.SimulationconfigurationTab);
            app.FocusScenarycamerainorbitalplatformCheckBox.Text = 'Focus Scenary camera in orbital platform';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UITable = uitable(app.ResultsTab);
            app.UITable.ColumnName = {'asd'; 'asd'; 'asd'; 'asd'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.ExporttoxlsxfileButton = uibutton(app.ResultsTab, 'push');
            app.ExporttoxlsxfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoxlsxfileButtonPushed, true);
            app.ExporttoxlsxfileButton.Visible = 'off';
            app.ExporttoxlsxfileButton.Text = 'Export to .xlsx file';
            app.ExporttocsvfileButton = uibutton(app.ResultsTab, 'push');
            app.ExporttocsvfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttocsvfileButtonPushed, true);
            app.ExporttocsvfileButton.Visible = 'off';
            app.ExporttocsvfileButton.Text = 'Export to .csv file';
            app.GeneratemissionplanButton = uibutton(app.ResultsTab, 'push');
            app.GeneratemissionplanButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratemissionplanButtonPushed, true);
            app.GeneratemissionplanButton.Visible = 'off';
            app.GeneratemissionplanButton.Text = 'Generate mission plan';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.AuthorCsarBoraoMoratinosLabel = uilabel(app.InformationTab);
            app.AuthorCsarBoraoMoratinosLabel.Text = 'Author: César Borao Moratinos';
            app.DegreeAerospaceEngineeringinNavigationLabel = uilabel(app.InformationTab);
            app.DegreeAerospaceEngineeringinNavigationLabel.Text = 'Degree: Aerospace Engineering in Navigation';
            app.SoftwareVersionv10Label = uilabel(app.InformationTab);
            app.SoftwareVersionv10Label.Text = 'Software Version: v1.0';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mission

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end